classdef HoTrongHai_BTVN2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        ThngtinTab          matlab.ui.container.Tab
        TPHCM012026Label    matlab.ui.control.Label
        GVHD                matlab.ui.control.Label
        LOGO_FETEL          matlab.ui.control.Image
        HoTrongHai          matlab.ui.control.Image
        LOP                 matlab.ui.control.Label
        MSSV                matlab.ui.control.Label
        TEN                 matlab.ui.control.Label
        MON_PP_TINH         matlab.ui.control.Label
        LOGO_KHTN           matlab.ui.control.Image
        BAO_CAO             matlab.ui.control.Label
        Chng8Tab            matlab.ui.container.Tab
        RUN_C8              matlab.ui.control.Button
        KQ_C8               matlab.ui.control.TextArea
        SaiSo               matlab.ui.control.EditField
        SAISLabel_2         matlab.ui.control.Label
        SoBuocLap           matlab.ui.control.EditField
        SAISLabel           matlab.ui.control.Label
        PhuongPhap          matlab.ui.control.DropDown
        PHNGPHPLabel        matlab.ui.control.Label
        KhoangPhanLy        matlab.ui.control.EditField
        KHONGPHNLYONLabel   matlab.ui.control.Label
        PhuongTrinh         matlab.ui.control.EditField
        NHPPHNGTRNHLabel    matlab.ui.control.Label
        Chng9Tab            matlab.ui.container.Tab
        RUN_C9              matlab.ui.control.Button
        KQ_C9               matlab.ui.control.TextArea
        MaTranB             matlab.ui.control.EditField
        MATRNTCHLabel       matlab.ui.control.Label
        MaTranA             matlab.ui.control.EditField
        NHPMATRNTHASLabel   matlab.ui.control.Label
        Chng10Tab           matlab.ui.container.Tab
        X0_C10              matlab.ui.control.EditField
        NHPX0Label          matlab.ui.control.Label
        RUN_C10             matlab.ui.control.Button
        KQ_C10              matlab.ui.control.TextArea
        Y                   matlab.ui.control.EditField
        NHPDLIUYLabel       matlab.ui.control.Label
        X                   matlab.ui.control.EditField
        NHPDLIUXLabel       matlab.ui.control.Label
        DoThi_C10           matlab.ui.control.UIAxes
        Chng1112Tab         matlab.ui.container.Tab
        X0_C1112            matlab.ui.control.EditField
        SAISLabel_4         matlab.ui.control.Label
        Y0_C1112            matlab.ui.control.EditField
        SAISLabel_3         matlab.ui.control.Label
        PhuongTrinh_C1112   matlab.ui.control.EditField
        NHPPHNGTRNHLabel_2  matlab.ui.control.Label
        RUN_C1112           matlab.ui.control.Button
        KQ_C1112            matlab.ui.control.TextArea
        DoThi_C1112         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
%% ===================== BÀI 3.3 =====================
function [x, err, err_est] = lapDon_B33(app, N)
    g = @(x) (12.75 - sqrt(1.65)*x.^3 - 1.12*x.^2)/14.35;
    q = 0.838;

    x = zeros(1,N+1);
    x(1) = 0.5;   % đúng bài tay

    for k = 1:N
        x(k+1) = g(x(k));
        err(k) = abs(x(k+1)-x(k));
    end

    err_est = q/(1-q)*err(end);
end

function [x, err, err_est] = newton_B33(app, N)
    f  = @(x) sqrt(1.65)*x.^3 + 1.12*x.^2 + 14.35*x - 12.75;
    df = @(x) 3*sqrt(1.65)*x.^2 + 2.24*x + 14.35;

    x = zeros(1,N+1);
    x(1) = 1.5;   % đúng bài tay

    for k = 1:N
        x(k+1) = x(k) - f(x(k))/df(x(k));
        err(k) = abs(x(k+1)-x(k));
    end

    m1 = df(0.5);
    err_est = abs(f(x(end)))/m1;
end
      
%% ===================== BÀI 4.3 =====================
function x = choleskySolve(app, A, b)
    n = size(A,1);
    L = zeros(n);

    for i=1:n
        for j=1:i
            s = sum(L(i,1:j-1).*L(j,1:j-1));
            if i==j
                L(i,j)=sqrt(A(i,i)-s);
            else
                L(i,j)=(A(i,j)-s)/L(j,j);
            end
        end
    end

    y = zeros(n,1);
    for i=1:n
        y(i)=(b(i)-L(i,1:i-1)*y(1:i-1))/L(i,i);
    end

    x = zeros(n,1);
    for i=n:-1:1
        x(i)=(y(i)-L(i+1:n,i)'*x(i+1:n))/L(i,i);
    end
end

%% ===================== BÀI 5.8 =====================
function [val,P] = noiSuy_B58(app)
    x = [6.3 6.72];
    y = [21.4259 23.377];
    a1 = (y(2)-y(1))/(x(2)-x(1));
    a0 = y(1)-a1*x(1);

    val = a0 + a1*6.51;
    syms X
    P = vpa(a0 + a1*X,6);
end

%% ===================== BÀI 6.7 =====================
function [X,Y] = euler(app)
    f=@(x,y)x^2+y^2;
    h=0.1; X=0:h:0.5; Y=zeros(size(X)); Y(1)=-1;
    for i=1:length(X)-1
        Y(i+1)=Y(i)+h*f(X(i),Y(i));
    end
end

function [X,Y] = eulerCaiTien(app)
    f=@(x,y)x^2+y^2;
    h=0.1; X=0:h:0.5; Y=zeros(size(X)); Y(1)=-1;
    for i=1:length(X)-1
        y0=Y(i)+h*f(X(i),Y(i));
        Y(i+1)=Y(i)+h/2*(f(X(i),Y(i))+f(X(i+1),y0));
    end
end

function [X,Y] = rk4(app)
    f=@(x,y)x^2+y^2;
    h=0.1; X=0:h:0.5; Y=zeros(size(X)); Y(1)=-1;
    for i=1:length(X)-1
        k1=f(X(i),Y(i));
        k2=f(X(i)+h/2,Y(i)+h*k1/2);
        k3=f(X(i)+h/2,Y(i)+h*k2/2);
        k4=f(X(i)+h,Y(i)+h*k3);
        Y(i+1)=Y(i)+h/6*(k1+2*k2+2*k3+k4);
    end
end
    end %method
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUN_C8
        function RUN_C8ButtonPushed(app, event)
N = 3;   % chỉ tính đến x3 đúng đề bài

if strcmp(app.PhuongPhap.Value,'LẶP ĐƠN')
    [x,~,err] = app.lapDon_B33(N);
    method = 'LẶP ĐƠN';
else
    [x,~,err] = app.newton_B33(N);
    method = 'NEWTON';
end

txt = ['PHƯƠNG PHÁP ', method, newline];
for i = 1:N
    txt = sprintf('%sx%d = %.6f\n', txt, i, x(i+1));
end
txt = sprintf('%s\nSai số lý thuyết ≤ %.6e', txt, err);

app.KQ_C8.Value = txt;
        end

        % Button pushed function: RUN_C9
        function RUN_C9ButtonPushed(app, event)
A = str2num(app.MaTranA.Value);
b = str2num(app.MaTranB.Value);

x = app.choleskySolve(A,b);

 txt = sprintf('NGHIỆM HỆ PHƯƠNG TRÌNH:\n');
for i = 1:length(x)
    if abs(x(i)) < 1e-4
        txt = sprintf('%sx%d = %.8e\n', txt, i, x(i));
    else
        txt = sprintf('%sx%d = %.6f\n', txt, i, x(i));
    end
end
app.KQ_C9.Value = txt;
        end

        % Button pushed function: RUN_C10
        function RUN_C10ButtonPushed(app, event)
try
    % ====== DỮ LIỆU ======
    xa = str2num(app.X.Value);
    ya = str2num(app.Y.Value);
    x0 = str2double(app.X0_C10.Value);

    % ====== NỘI SUY NEWTON BẬC 1 (2 ĐIỂM GẦN NHẤT) ======
    [~,idx] = sort(abs(xa - x0));
    xa2 = xa(idx(1:2));
    ya2 = ya(idx(1:2));

    syms x
    P = ya2(1) + (ya2(2)-ya2(1))/(xa2(2)-xa2(1))*(x-xa2(1));
    P = vpa(P,6);

    val = double(subs(P,x,x0));

    % ====== HIỂN THỊ ======
    txt = 'BÀI 5.8 – NỘI SUY NEWTON BẬC 1';
    txt = sprintf('%s\nP(x) = %s',txt,char(P));
    txt = sprintf('%s\nf(%.2f) ≈ %.6f',txt,x0,val);
    app.KQ_C10.Value = txt;

    % ====== VẼ ĐỒ THỊ ======
    cla(app.DoThi_C10)
    hold(app.DoThi_C10,'on')

    plot(app.DoThi_C10,xa,ya,'ro','MarkerFaceColor','r')
    fP = matlabFunction(P);
    xx = linspace(min(xa),max(xa),100);
    plot(app.DoThi_C10,xx,fP(xx),'b','LineWidth',2)
    plot(app.DoThi_C10,x0,val,'ks','MarkerFaceColor','g')

    legend(app.DoThi_C10,'Dữ liệu','Đa thức nội suy','Giá trị cần tính')
    grid(app.DoThi_C10,'on')
    hold(app.DoThi_C10,'off')

catch ME
    app.KQ_C10.Value = ME.message;
end
        end

        % Button pushed function: RUN_C1112
        function RUN_C1112ButtonPushed(app, event)
try
    f = @(x,y) x.^2 + y.^2;

    h = 0.1;
    x = 0:h:0.5;
    n = length(x);

    % ====== EULER ======
    yE = zeros(1,n);
    yE(1) = -1;
    for i = 1:n-1
        yE(i+1) = yE(i) + h*f(x(i),yE(i));
    end

    % ====== EULER CẢI TIẾN ======
    yEC = zeros(1,n);
    yEC(1) = -1;
    for i = 1:n-1
        y_tam = yEC(i) + h*f(x(i),yEC(i));
        yEC(i+1) = yEC(i) + h/2*(f(x(i),yEC(i)) + f(x(i+1),y_tam));
    end

    % ====== RUNGE–KUTTA BẬC 4 ======
    yRK = zeros(1,n);
    yRK(1) = -1;
    for i = 1:n-1
        k1 = h*f(x(i),yRK(i));
        k2 = h*f(x(i)+h/2,yRK(i)+k1/2);
        k3 = h*f(x(i)+h/2,yRK(i)+k2/2);
        k4 = h*f(x(i)+h,yRK(i)+k3);
        yRK(i+1) = yRK(i) + (k1+2*k2+2*k3+k4)/6;
    end

    % ====== HIỂN THỊ BẢNG ======
    txt = 'x     Euler       Euler CT     RK4';
    for i = 1:n
        txt = sprintf('%s\n%.1f  %.6f  %.6f  %.6f',txt,x(i),yE(i),yEC(i),yRK(i));
    end
    app.KQ_C1112.Value = txt;

    % ====== VẼ ĐỒ THỊ ======
    cla(app.DoThi_C1112)
    hold(app.DoThi_C1112,'on')

    plot(app.DoThi_C1112,x,yE,'r--o','LineWidth',1.5)
    plot(app.DoThi_C1112,x,yEC,'b-.s','LineWidth',1.5)
    plot(app.DoThi_C1112,x,yRK,'g-o','LineWidth',2)

    legend(app.DoThi_C1112,'Euler','Euler cải tiến','RK4')
    grid(app.DoThi_C1112,'on')
    hold(app.DoThi_C1112,'off')

catch ME
    app.KQ_C1112.Value = ME.message;
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ThngtinTab = uitab(app.TabGroup);
            app.ThngtinTab.Title = 'Thông tin';
            app.BAO_CAO = uilabel(app.ThngtinTab);
            app.BAO_CAO.Text = 'BÁO CÁO GIỮA KÌ';
            app.LOGO_KHTN = uiimage(app.ThngtinTab);
            app.LOGO_KHTN.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.MON_PP_TINH = uilabel(app.ThngtinTab);
            app.MON_PP_TINH.Text = 'Môn học:  Phương pháp tính và Matlab';
            app.TEN = uilabel(app.ThngtinTab);
            app.TEN.Text = 'Họ và tên: Hồ Trọng Hải';
            app.MSSV = uilabel(app.ThngtinTab);
            app.MSSV.Text = 'MSSV: 23207050';
            app.LOP = uilabel(app.ThngtinTab);
            app.LOP.Text = 'Lớp: 23DTV_CLC1';
            app.HoTrongHai = uiimage(app.ThngtinTab);
            app.HoTrongHai.ImageSource = fullfile(pathToMLAPP, 'ChanDung.jpg');
            app.LOGO_FETEL = uiimage(app.ThngtinTab);
            app.LOGO_FETEL.ImageSource = fullfile(pathToMLAPP, 'logo-fetel.png');
            app.GVHD = uilabel(app.ThngtinTab);
            app.GVHD.Text = 'GVHD: TS. Nguyễn Xuân Vinh';
            app.TPHCM012026Label = uilabel(app.ThngtinTab);
            app.TPHCM012026Label.Text = 'TPHCM - 01/2026';
            app.Chng8Tab = uitab(app.TabGroup);
            app.Chng8Tab.Title = 'Chương 8';
            app.NHPPHNGTRNHLabel = uilabel(app.Chng8Tab);
            app.NHPPHNGTRNHLabel.Text = '1. Nhập phương trình';
            app.PhuongTrinh = uieditfield(app.Chng8Tab, 'text');
            app.PhuongTrinh.Value = 'sqrt(1.65)*x^3 + 1.12*x^2 + 14.35*x - 12.75';
            app.KHONGPHNLYONLabel = uilabel(app.Chng8Tab);
            app.KHONGPHNLYONLabel.Text = '2. Khoảng phân li';
            app.KhoangPhanLy = uieditfield(app.Chng8Tab, 'text');
            app.KhoangPhanLy.Value = '0.5 1.5';
            app.PHNGPHPLabel = uilabel(app.Chng8Tab);
            app.PHNGPHPLabel.Text = '3.Phương pháp';
            app.PhuongPhap = uidropdown(app.Chng8Tab);
            app.PhuongPhap.Items = {'LẶP ĐƠN', 'NEWTON'};
            app.PhuongPhap.Value = 'LẶP ĐƠN';
            app.SAISLabel = uilabel(app.Chng8Tab);
            app.SAISLabel.Text = '5. Số bước lặp';
            app.SoBuocLap = uieditfield(app.Chng8Tab, 'text');
            app.SAISLabel_2 = uilabel(app.Chng8Tab);
            app.SAISLabel_2.Text = '4. Sai số';
            app.SaiSo = uieditfield(app.Chng8Tab, 'text');
            app.KQ_C8 = uitextarea(app.Chng8Tab);
            app.RUN_C8 = uibutton(app.Chng8Tab, 'push');
            app.RUN_C8.ButtonPushedFcn = createCallbackFcn(app, @RUN_C8ButtonPushed, true);
            app.RUN_C8.Interpreter = 'latex';
            app.RUN_C8.Text = 'RUN ';
            app.Chng9Tab = uitab(app.TabGroup);
            app.Chng9Tab.Title = 'Chương 9';
            app.NHPMATRNTHASLabel = uilabel(app.Chng9Tab);
            app.NHPMATRNTHASLabel.Text = '1. Nhập ma trận A';
            app.MaTranA = uieditfield(app.Chng9Tab, 'text');
            app.MaTranA.Value = '[5 3 2 1; 3 6 1 2; 2 1 5 1; 1 2 1 6]';
            app.MATRNTCHLabel = uilabel(app.Chng9Tab);
            app.MATRNTCHLabel.Text = '2. Nhập ma trận B ';
            app.MaTranB = uieditfield(app.Chng9Tab, 'text');
            app.MaTranB.Value = '[1; 4; 7; 2]';
            app.KQ_C9 = uitextarea(app.Chng9Tab);
            app.RUN_C9 = uibutton(app.Chng9Tab, 'push');
            app.RUN_C9.ButtonPushedFcn = createCallbackFcn(app, @RUN_C9ButtonPushed, true);
            app.RUN_C9.Interpreter = 'latex';
            app.RUN_C9.Text = 'RUN ';
            app.Chng10Tab = uitab(app.TabGroup);
            app.Chng10Tab.Title = 'Chương 10';
            app.DoThi_C10 = uiaxes(app.Chng10Tab);
            title(app.DoThi_C10, 'ĐỒ THỊ')
            xlabel(app.DoThi_C10, 'X')
            ylabel(app.DoThi_C10, 'Y')
            zlabel(app.DoThi_C10, 'Z')
            app.NHPDLIUXLabel = uilabel(app.Chng10Tab);
            app.NHPDLIUXLabel.Text = '1. Nhập dữ liệu X';
            app.X = uieditfield(app.Chng10Tab, 'text');
            app.X.Value = '6.3, 6.72, 7.14, 7.56, 7.98, 8.4';
            app.NHPDLIUYLabel = uilabel(app.Chng10Tab);
            app.NHPDLIUYLabel.Text = '2. Nhập dữ liệu Y';
            app.Y = uieditfield(app.Chng10Tab, 'text');
            app.Y.Value = '21.4259, 23.377, 25.3622, 27.3831, 29.438, 31.5253';
            app.KQ_C10 = uitextarea(app.Chng10Tab);
            app.RUN_C10 = uibutton(app.Chng10Tab, 'push');
            app.RUN_C10.ButtonPushedFcn = createCallbackFcn(app, @RUN_C10ButtonPushed, true);
            app.RUN_C10.Interpreter = 'latex';
            app.RUN_C10.Text = 'RUN ';
            app.NHPX0Label = uilabel(app.Chng10Tab);
            app.NHPX0Label.Text = '3. Nhập X0';
            app.X0_C10 = uieditfield(app.Chng10Tab, 'text');
            app.X0_C10.Value = '6.51';
            app.Chng1112Tab = uitab(app.TabGroup);
            app.Chng1112Tab.Title = 'Chương 11-12';
            app.DoThi_C1112 = uiaxes(app.Chng1112Tab);
            title(app.DoThi_C1112, 'Đồ thị')
            xlabel(app.DoThi_C1112, 'X')
            ylabel(app.DoThi_C1112, 'Y')
            zlabel(app.DoThi_C1112, 'Z')
            app.KQ_C1112 = uitextarea(app.Chng1112Tab);
            app.RUN_C1112 = uibutton(app.Chng1112Tab, 'push');
            app.RUN_C1112.ButtonPushedFcn = createCallbackFcn(app, @RUN_C1112ButtonPushed, true);
            app.RUN_C1112.Interpreter = 'latex';
            app.RUN_C1112.Text = 'RUN ';
            app.NHPPHNGTRNHLabel_2 = uilabel(app.Chng1112Tab);
            app.NHPPHNGTRNHLabel_2.Text = '1. Nhập phương trình';
            app.PhuongTrinh_C1112 = uieditfield(app.Chng1112Tab, 'text');
            app.PhuongTrinh_C1112.Value = 'x^2 + y^2';
            app.SAISLabel_3 = uilabel(app.Chng1112Tab);
            app.SAISLabel_3.Text = '3. Nhập Y(X0)';
            app.Y0_C1112 = uieditfield(app.Chng1112Tab, 'text');
            app.Y0_C1112.Value = '-1';
            app.SAISLabel_4 = uilabel(app.Chng1112Tab);
            app.SAISLabel_4.Text = '2. Nhập X0';
            app.X0_C1112 = uieditfield(app.Chng1112Tab, 'text');
            app.X0_C1112.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoTrongHai_BTVN2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end